classdef app12 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DetectVoiceButton               matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        VoiceTypeLabel                  matlab.ui.control.Label
        PitchValueLabel                 matlab.ui.control.Label
        EstimatedPitchLabel             matlab.ui.control.Label
        PlayFilteredButton              matlab.ui.control.Button
        RemoveNoiseButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        TimeDomainTab                   matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        FrequencyDomainFFTTab           matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        FFTSpectrumButton               matlab.ui.control.Button
        PlotSignalButton                matlab.ui.control.Button
        PlayFirstsecEditField           matlab.ui.control.NumericEditField
        PlayFirstsecEditFieldLabel      matlab.ui.control.Label
        PlaySelectedButton              matlab.ui.control.Button
        PlayOriginalButton              matlab.ui.control.Button
        RecordingTimesecEditField       matlab.ui.control.NumericEditField
        RecordingTimesecEditFieldLabel  matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        audioData
        filteredAudio

        Fs
        pitchValue
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
       
                Fs = 44100;
                recordTime = app.RecordingTimesecEditField.Value;
                recObj = audiorecorder(Fs,16,1);
                recordblocking(recObj, recordTime);
                audioData = getaudiodata(recObj);
             
                app.audioData = audioData;
                app.Fs = Fs;

                % --- Save recorded audio to file ---
                saveFolder = fullfile(pwd, 'RecordedAudio');
                if ~exist(saveFolder, 'dir')
                    mkdir(saveFolder);
                end
                timestamp = string(datetime('now'), 'yyyyMMdd_HHmmss');
                filename = fullfile(saveFolder, sprintf('original_%s.wav', timestamp));
                audiowrite(filename, audioData, Fs);

                uialert(app.UIFigure, sprintf('Voice Recorded and Saved:\n%s', filename), 'Recording Complete');
         
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            if isempty(app.audioData)
                uialert(app.UIFigure,'Please record audio first!','No Audio');
                return;
            end
            sound(app.audioData, app.Fs);
        end

        % Button pushed function: PlaySelectedButton
        function PlaySelectedButtonPushed(app, event)


                if isempty(app.audioData)
                    uialert(app.UIFigure,'Please record audio first!','No Audio');
                    return;
                end

                seconds = app.PlayFirstsecEditField.Value;
                N = min(round(seconds*app.Fs),length(app.audioData));

                sound(app.audioData(1:N),app.Fs);
            
        end

        % Button pushed function: PlotSignalButton
        function PlotSignalButtonPushed(app, event)
         
                if isempty(app.audioData)

                    uialert(app.UIFigure,'Please record audio first!','No Audio');

                    return;

                end
                t = (0:length(app.audioData)-1)/app.Fs;
                plot(app.UIAxes, t, app.audioData);
                title(app.UIAxes,'Speech Signal - Time Domain');
                xlabel(app.UIAxes,'Time (seconds)');
                ylabel(app.UIAxes,'Amplitude');
                grid(app.UIAxes,'on');
            
        end

        % Button pushed function: FFTSpectrumButton
        function FFTSpectrumButtonPushed(app, event)
            if isempty(app.audioData)

                uialert(app.UIFigure,'Please record audio first!','No Audio');

                return;

            end
            N = length(app.audioData);
            signal = app.audioData - mean(app.audioData);

            Y = fft(signal);

            P2 = abs(Y/N);

            P1 = P2(1:N/2+1);

            P1(2:end-1) = 2*P1(2:end-1);

            f = app.Fs*(0:(N/2))/N;

            plot(app.UIAxes2,f,P1)
            xlim(app.UIAxes2,[0 5000]);

            xlabel(app.UIAxes2,'Frequency (Hz)')

            ylabel(app.UIAxes2,'Magnitude')

            title(app.UIAxes2,'Single-Sided FFT Spectrum')

            grid(app.UIAxes2,'on')
        end

        % Button pushed function: RemoveNoiseButton
        function RemoveNoiseButtonPushed(app, event)
          

           % Check if audio exists
                if isempty(app.audioData)
                    uialert(app.UIFigure,'Please record audio first!','No Audio');
                    return;
                end

                % Design low-pass filter
                d = designfilt('lowpassiir', ...
                    'FilterOrder',8,...
                    'PassbandFrequency',3000,...
                    'PassbandRipple',0.2,...
                    'SampleRate',app.Fs);

                % Remove noise
                app.filteredAudio = filtfilt(d, app.audioData);

                % Create folder if it doesn't exist
                saveFolder = fullfile(pwd,'RecordedAudio');
                if ~exist(saveFolder,'dir')
                    mkdir(saveFolder);
                end

                % Generate filename with timestamp
                timestamp = datestr(now,'yyyymmdd_HHMMSS');
                filename = fullfile(saveFolder, ['filtered_' timestamp '.wav']);

                % Save filtered audio
                audiowrite(filename, app.filteredAudio, app.Fs);

                % Show success message
                uialert(app.UIFigure,...
                    sprintf('Noise Removed Successfully!\n\nSaved As:\n%s',filename),...
                    'Done');

          
        end

        % Button pushed function: PlayFilteredButton
        function PlayFilteredButtonn(app, event)
            if isempty(app.filteredAudio)

                uialert(app.UIFigure,'Please remove noise first!','No Filtered Audio');

                return;

            end

            sound(app.filteredAudio,app.Fs);
        end

        % Button pushed function: DetectVoiceButton
        function DetectVoiceButtonPushed(app, event)
           

                % Check if audio exists
                if isempty(app.audioData)
                    uialert(app.UIFigure,'Please record audio first.','No Audio');
                    return;
                end

                % Use filtered audio if available
                if ~isempty(app.filteredAudio)
                    x = app.filteredAudio;
                else
                    x = app.audioData;
                end

                Fs = app.Fs;

                % Convert to column vector
                x = x(:);

                % Remove DC component
                x = x - mean(x);
                x = x / max(abs(x));
                startSample = round(0.5*Fs);
                endSample = min(round(3*Fs), length(x));

                x = x(startSample:endSample);

                % Autocorrelation
                [r,lags] = xcorr(x);

                % Keep positive lags only
                r = r(lags >= 0);

                % Pitch search range (80–300 Hz)
                minLag = round(Fs/300);
                maxLag = round(Fs/80);

                % Find maximum peak
                [~,idx] = max(r(minLag:maxLag));
                lag = idx + minLag - 1;

                pitchHz = Fs / lag;

                % Display pitch
                app.PitchValueLabel.Text = sprintf('%.2f Hz', pitchHz);

                if pitchHz < 150
                    app.Label_2.Text = 'Male';
                else
                    app.Label_2.Text = 'Female';
                end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Speech Signal Analysis System';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.RecordingTimesecEditFieldLabel = uilabel(app.UIFigure);
            app.RecordingTimesecEditFieldLabel.Text = 'Recording Time (sec)';
            app.RecordingTimesecEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecordingTimesecEditField.Value = 8;
            app.PlayOriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlaySelectedButton = uibutton(app.UIFigure, 'push');
            app.PlaySelectedButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySelectedButtonPushed, true);
            app.PlaySelectedButton.Text = 'Play Selected';
            app.PlayFirstsecEditFieldLabel = uilabel(app.UIFigure);
            app.PlayFirstsecEditFieldLabel.Text = 'Play First (sec)';
            app.PlayFirstsecEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlayFirstsecEditField.Value = 3;
            app.PlotSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSignalButtonPushed, true);
            app.PlotSignalButton.Text = 'Plot Signal';
            app.FFTSpectrumButton = uibutton(app.UIFigure, 'push');
            app.FFTSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @FFTSpectrumButtonPushed, true);
            app.FFTSpectrumButton.Text = 'FFT Spectrum';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = ' Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FrequencyDomainFFTTab = uitab(app.TabGroup);
            app.FrequencyDomainFFTTab.Title = 'Frequency Domain (FFT)';
            app.UIAxes2 = uiaxes(app.FrequencyDomainFFTTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RemoveNoiseButton = uibutton(app.UIFigure, 'push');
            app.RemoveNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNoiseButtonPushed, true);
            app.RemoveNoiseButton.Text = 'Remove Noise';
            app.PlayFilteredButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredButtonn, true);
            app.PlayFilteredButton.Text = 'Play Filtered';
            app.EstimatedPitchLabel = uilabel(app.UIFigure);
            app.EstimatedPitchLabel.Text = 'Estimated Pitch:';
            app.PitchValueLabel = uilabel(app.UIFigure);
            app.PitchValueLabel.Text = '---';
            app.VoiceTypeLabel = uilabel(app.UIFigure);
            app.VoiceTypeLabel.Text = 'Voice Type:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '---';
            app.DetectVoiceButton = uibutton(app.UIFigure, 'push');
            app.DetectVoiceButton.ButtonPushedFcn = createCallbackFcn(app, @DetectVoiceButtonPushed, true);
            app.DetectVoiceButton.Text = 'Detect Voice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app12

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end